classdef ReMi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        RestartButton               matlab.ui.control.Button
        PickDisp                    matlab.ui.control.Button
        NodeDistanceEditField       matlab.ui.control.NumericEditField
        NodeDistanceEditFieldLabel  matlab.ui.control.Label
        startTimeExample            matlab.ui.control.Label
        EndTimeUTCEditField         matlab.ui.control.EditField
        EndTimeUTCEditFieldLabel    matlab.ui.control.Label
        StartTimeUTCEditField       matlab.ui.control.EditField
        StartTimeUTCEditFieldLabel  matlab.ui.control.Label
        DataCutTips                 matlab.ui.control.Label
        DataCut                     matlab.ui.control.EditField
        IsitnecessarytocutthedataEditFieldLabel  matlab.ui.control.Label
        group06                     matlab.ui.control.Label
        group05                     matlab.ui.control.Label
        group04                     matlab.ui.control.Label
        group03                     matlab.ui.control.Label
        group02                     matlab.ui.control.Label
        group01                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        dataPath                    matlab.ui.control.Label
        tips                        matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        SetPathButton               matlab.ui.control.Button
        LoadDataButton              matlab.ui.control.Button
        REMIButton                  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        DispCurve                   matlab.ui.control.UIAxes
        Traces                      matlab.ui.control.UIAxes
        Freq                        matlab.ui.control.UIAxes
        callREMI                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        fileName;
        isDataCut;
        Data;
        startTime;
        endTime;
        numPoints;
        startRecord;
        endRecord;
        Fs;
        sliceData;
        xlabelTime;
        seis_all;
        nodeDistance;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetPathButton
        function SetPathButtonPushed(app, event)
                        
            % select the sac data and slice the data
            filePath = uigetdir('*.*','Please select the Folder:');
            app.fileName = RangTraversal(filePath);  % get all the file name, using function "RangTraversal"

        end

        % Value changed function: DataCut
        function DataCutValueChanged(app, event)
            
            % judge if is necessary to cut the data
            value = app.DataCut.Value;
            app.isDataCut = upper(value);

        end

        % Value changed function: StartTimeUTCEditField
        function StartTimeUTCEditFieldValueChanged(app, event)
            
            % get the start time
            value = app.StartTimeUTCEditField.Value;
            app.startTime = datetime(value,'InputFormat','yyyy-MM-dd HH:mm:ss');

        end

        % Value changed function: EndTimeUTCEditField
        function EndTimeUTCEditFieldValueChanged(app, event)
            
            % get the end time
            value = app.EndTimeUTCEditField.Value;
            app.endTime = datetime(value,'InputFormat','yyyy-MM-dd HH:mm:ss');

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % load the data
            if app.isDataCut == 'Y'
                warning off;
                app.Data = {};
                j = 0;
                for i = 1:length(app.fileName)
                    if contains(app.fileName{i}, 'Z')  % Z component
                        j = j+1;
                        app.Data{j} = rdsac(app.fileName{i});
                    end
                end
                % get the Ifo of sac data
                app.numPoints = {};
                app.startRecord = {};
                app.endRecord = {};
                for i = 1:length(app.Data)
                    app.numPoints{i} = length(app.Data{1,i}.d);
                    app.startRecord{i} = datetime(app.Data{1,i}.t(1,1),'ConvertFrom','datenum');
                    app.endRecord{i} = datetime(app.Data{1,i}.t(end,1),'ConvertFrom','datenum');
                end
                app.Fs = 1/app.Data{1,1}.HEADER.DELTA;  % get the sampling rate from header
                % slice the sac data
                duration = app.endTime - app.startTime;
                [~,~,~,hours,minutes,Seconds] = datevec(duration);
                sliceTime = 1000 * (3600 * hours + 60 * minutes + Seconds);  % slice time duration in milliseconds
                pointsRecord = round(sliceTime/(1000/app.Fs));  % points record in the slice time

                intervals = {};
                beforeSliceTime = {};
                beforeSlicePoints = {};
                app.sliceData = {};
                for i = 1:length(app.Data)
                    intervals{i} = app.startTime - app.startRecord{1,i};
                    [~,~,~,hours,minutes,Seconds] = datevec(intervals{1,i});
                    beforeSliceTime{i} = 1000 * (3600 * hours + 60 * minutes + Seconds);
                    beforeSlicePoints{i} = round(beforeSliceTime{1,i} / (1000/app.Fs));
                    app.sliceData{i} = app.Data{1,i}.d(beforeSlicePoints{1,i}:(beforeSlicePoints{1,i} + pointsRecord));
                    app.xlabelTime{i} = app.Data{1,i}.t(beforeSlicePoints{1,i}:(beforeSlicePoints{1,i} + pointsRecord));
                end
                % plot the Time dommin
                cla(app.Traces,'reset');
                for i = 1:length(app.Data)
                    plot(app.Traces,app.xlabelTime{1,i},app.sliceData{1,i})
                    hold(app.Traces,"on")
                end
                % set the figure layout
                datetick(app.Traces, 'x', 'HH:MM:SS');
                xlabel(app.Traces, 'Time');
                ylabel(app.Traces, 'X(t)');
                title(app.Traces, 'Traces');
                grid(app.Traces, "on");
                % plot the Freq domin
                cla(app.Freq,"reset")
                for i = 1:length(app.Data)
                    [f, P1] = fftCal(app.sliceData{1,i}, app.Fs);
                    semilogx(app.Freq, f, P1)
                    hold(app.Freq,"on")
                end
                xlim(app.Freq,[1 100]);
                xlabel(app.Freq, 'Frequency (Hz)');
                ylabel(app.Freq, '|P1(f)|');
                title(app.Freq, 'Freq');
                grid(app.Freq,"on");
            else
                warning off;
                % read the sac data
                app.Data = {};
                j = 0;
                for i = 1:length(app.fileName)
                    if contains(app.fileName{i}, 'sac')
                        j = j+1;
                        app.Data{j} = rdsac(app.fileName{i,1});
                    end      
                end

                % plot the Time domin
                cla(app.Traces,'reset');
                for i = 1:length(app.Data)
                    plot(app.Traces,app.Data{1,i}.t, app.Data{1,i}.d)
                    hold(app.Traces,"on")
                end
                app.Fs =  1/app.Data{1,1}.HEADER.DELTA;
                % set the figure layout
                datetick(app.Traces, 'x', 'HH:MM:SS');
                xlabel(app.Traces, 'Time');
                ylabel(app.Traces, 'X(t)');
                title(app.Traces, 'Traces');
                grid(app.Traces, "on");
                % plot the Freq domin
                cla(app.Freq,"reset")
                for i = 1:length(app.Data)
                    [f, P1] = fftCal(app.Data{1,i}.d, app.Fs);
                    semilogx(app.Freq, f, P1)
                    hold(app.Freq,"on")
                end
                xlim(app.Freq,[1 100]);
                xlabel(app.Freq, 'Frequency (Hz)');
                ylabel(app.Freq, '|P1(f)|');
                title(app.Freq, 'Freq');
                grid(app.Freq,"on");
            end

        end

        % Value changed function: NodeDistanceEditField
        function NodeDistanceEditFieldValueChanged(app, event)
            
            % set the node distance
            value = app.NodeDistanceEditField.Value;
            app.nodeDistance = value;

        end

        % Button pushed function: REMIButton
        function REMIButtonPushed(app, event)
            
            % transform the sac data from cell to array
            if app.isDataCut == 'Y'
                for i = 1:length(app.sliceData)
                    app.seis_all(:,i) = app.sliceData{1,i};
                end
                n = length(app.sliceData);
            else
                for i = 1:length(app.Data)
                    app.seis_all(:,i) = app.Data{1,i}.d;  % get the seis data
                end
                n = length(app.Data);
            end

            dn = app.nodeDistance;  % node distance
            n0 = 1;  % the start node number
            n_select = n;  % the node numbers for calculating
            % REMI parameters
            nw = 20;
            np = 200;
            npad = 4000;
            percent = 5;
            samplerate = app.Fs;
            % todo: to calculate the nodes in loops
            loop = 0;
            n_cut = 9;

            seis = app.seis_all(:,n0:n0 + n_select - 1);
            [RF_STACK,f,p] = Remi_func(n,dn,seis,nw,np,npad,percent,samplerate,loop,n_cut,n_select);
            [p_mesh, f_mesh] = meshgrid(p,f);
            cla(app.callREMI,'reset');
            pcolor(app.callREMI, f_mesh, p_mesh, RF_STACK);
            view(app.callREMI, [0 -90])
            shading(app.callREMI, "interp");
            colormap(app.callREMI, mymap('cmp_b2r'))
            caxis(app.callREMI, [0 60])
            colorbar(app.callREMI)
            xlim(app.callREMI, [0 50])
            xlabel(app.callREMI, 'Frequency (Hz)')
            ylabel(app.callREMI,'Slowness (s/m)')
            title(app.callREMI,'REMI')

        end

        % Button pushed function: PickDisp
        function PickDispButtonPushed(app, event)
            
            % pick the dispersion curve
            for i = 1:10  % points number = 10
                [x(i,1), y(i,1)] = selectDataPoints(app, app.callREMI);
            end
            cla(app.DispCurve,"reset")    
            plot(app.DispCurve, x, 1./y, 'Color', [0 0.4470 0.7410], 'LineWidth', 1.5, 'Marker', '*')
            xlabel(app.DispCurve, 'Frequency (Hz)')
            ylabel(app.DispCurve, 'Velocity (m/s)')
            title(app.DispCurve, 'Disp')
            grid(app.DispCurve,"on")

        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
                        
            % restart
            app.UIFigure.Visible = 'off';
            REMI();
            close(app.UIFigure);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {781, 781};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {187, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.REMIButton = uibutton(app.LeftPanel, 'push');
            app.REMIButton.ButtonPushedFcn = createCallbackFcn(app, @REMIButtonPushed, true);
            app.REMIButton.Text = 'REMI';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SetPathButton = uibutton(app.LeftPanel, 'push');
            app.SetPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetPathButtonPushed, true);
            app.SetPathButton.Text = 'Set Path';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ReMi.png');
            app.tips = uilabel(app.LeftPanel);
            app.tips.Text = 'Please select the data path';
            app.dataPath = uilabel(app.LeftPanel);
            app.dataPath.Text = 'E.g: DTCC\A';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '-->';
            app.group01 = uilabel(app.LeftPanel);
            app.group01.Text = '\1';
            app.group02 = uilabel(app.LeftPanel);
            app.group02.Text = '\2';
            app.group03 = uilabel(app.LeftPanel);
            app.group03.Text = '\3';
            app.group04 = uilabel(app.LeftPanel);
            app.group04.Text = '\4';
            app.group05 = uilabel(app.LeftPanel);
            app.group05.Text = '\5';
            app.group06 = uilabel(app.LeftPanel);
            app.group06.Text = '\6';
            app.IsitnecessarytocutthedataEditFieldLabel = uilabel(app.LeftPanel);
            app.IsitnecessarytocutthedataEditFieldLabel.Text = 'Is it necessary to cut the data?';
            app.DataCut = uieditfield(app.LeftPanel, 'text');
            app.DataCut.ValueChangedFcn = createCallbackFcn(app, @DataCutValueChanged, true);
            app.DataCutTips = uilabel(app.LeftPanel);
            app.DataCutTips.Text = 'Y/y for yes, N/n for no';
            app.StartTimeUTCEditFieldLabel = uilabel(app.LeftPanel);
            app.StartTimeUTCEditFieldLabel.Text = 'Start Time(UTC)';
            app.StartTimeUTCEditField = uieditfield(app.LeftPanel, 'text');
            app.StartTimeUTCEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeUTCEditFieldValueChanged, true);
            app.EndTimeUTCEditFieldLabel = uilabel(app.LeftPanel);
            app.EndTimeUTCEditFieldLabel.Text = 'End Time(UTC)';
            app.EndTimeUTCEditField = uieditfield(app.LeftPanel, 'text');
            app.EndTimeUTCEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeUTCEditFieldValueChanged, true);
            app.startTimeExample = uilabel(app.LeftPanel);
            app.startTimeExample.Text = 'E.g: 2022-03-23 09:00:00';
            app.NodeDistanceEditFieldLabel = uilabel(app.LeftPanel);
            app.NodeDistanceEditFieldLabel.Text = 'Node Distance';
            app.NodeDistanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NodeDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @NodeDistanceEditFieldValueChanged, true);
            app.PickDisp = uibutton(app.LeftPanel, 'push');
            app.PickDisp.ButtonPushedFcn = createCallbackFcn(app, @PickDispButtonPushed, true);
            app.PickDisp.Text = 'PickDisp';
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.RightPanel = uipanel(app.GridLayout);
            app.callREMI = uiaxes(app.RightPanel);
            title(app.callREMI, 'REMI')
            xlabel(app.callREMI, 'Frequency (Hz)')
            ylabel(app.callREMI, 'Slowness (s/m)')
            zlabel(app.callREMI, 'Z')
            app.Freq = uiaxes(app.RightPanel);
            title(app.Freq, 'Freq')
            xlabel(app.Freq, 'Frequency (Hz)')
            ylabel(app.Freq, '|P1(f)|')
            zlabel(app.Freq, 'Z')
            app.Traces = uiaxes(app.RightPanel);
            title(app.Traces, 'Traces')
            xlabel(app.Traces, 'Time')
            ylabel(app.Traces, 'X(t)')
            zlabel(app.Traces, 'Z')
            app.DispCurve = uiaxes(app.RightPanel);
            title(app.DispCurve, 'Disp')
            xlabel(app.DispCurve, 'Frequency (Hz)')
            ylabel(app.DispCurve, 'Velocity (m/s)')
            zlabel(app.DispCurve, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReMi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end